classdef AdeeptArmGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GoP3Button             matlab.ui.control.Button
        GoP2Button             matlab.ui.control.Button
        GoP1Button             matlab.ui.control.Button
        SaveP3Button           matlab.ui.control.Button
        SaveP2Button           matlab.ui.control.Button
        SaveP1Button           matlab.ui.control.Button
        HomeButton             matlab.ui.control.Button
        GoButton               matlab.ui.control.Button
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1Label            matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles = [0 0 0]
        p1 = [];
        p2 = [];
        p3 = [];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            

        % TODO: Create the A homogeneous transformation matrices for the given jointAngles.

        % TODO: Use the current A matricies to form the T0_n matricies.
        A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
        A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
        A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));

        T0_1 = A1;
        T0_2 = A1 * A2;
        T0_3 = A1 * A2 * A3;

        % TODO: Use the current T matricies to transform the original patch vertices
        link1VerticesWRTground = T0_1 * app.link1Vertices;
        link2VerticesWRTground = T0_2 * app.link2Vertices;
        link3VerticesWRTground = T0_3 * app.link3Vertices;
        
        % TODO: Update the patches with the newly transformed vertices
        set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
        set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
        set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Begin: Code that can go into your GUI's StartupFcn.
clc

% Prepare the axes_arm properties
app.axes_arm.View = [90 -50];
app.axes_arm.Visible = 'off';

% Once you are in Matlab App Designer you can try these axes_arm properties too.
% These properties keep the Axes from automatically resizing (optional)
% app.axes_arm.XLim = [-160 250];
% app.axes_arm.YLim = [-250 250];
% app.axes_arm.ZLim = [-22 22];

% Create vertices for all the patches
makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
% Save references to the vertices of each patch, make points 4x1 not 3x1.
app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
app.link1Vertices = get(app.link1Patch, 'Vertices')';
app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
app.link2Vertices = get(app.link2Patch, 'Vertices')';
app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
app.link3Vertices = get(app.link3Patch, 'Vertices')';
app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

app.updateArm()
% End: Code that can go into your GUI's opening function.
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            % TODO: Also update the numeric value text field
            app.J1ValueEditField.Value = app.jointAngles(1);
            app.updateArm
           
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            % TODO: Also update the numeric value text field
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.updateArm
           
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            % TODO: Also update the numeric value text field
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.updateArm
           
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.jointAngles(3) = app.J3ValueEditField.Value;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.jointAngles(2) = 0;
            app.jointAngles(3) = 0;

            app.Joint1Slider.Value = 0;
            app.Joint2Slider.Value = 0;
            app.Joint3Slider.Value = 0;

            app.J1ValueEditField.Value = 0;
            app.J2ValueEditField.Value = 0;
            app.J3ValueEditField.Value = 0;

            app.updateArm
        end

        % Button pushed function: SaveP1Button
        function SaveP1ButtonPushed(app, event)
            app.p1 = [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3)];
        end

        % Button pushed function: GoP1Button
        function GoP1ButtonPushed(app, event)
            if length(app.p1) == 3
            app.jointAngles(1) = app.p1(1);
            app.jointAngles(2) = app.p1(2);
            app.jointAngles(3) = app.p1(3);

            app.Joint1Slider.Value = app.p1(1);
            app.Joint2Slider.Value = app.p1(2);
            app.Joint3Slider.Value = app.p1(3);

            app.J1ValueEditField.Value = app.p1(1);
            app.J2ValueEditField.Value = app.p1(2);
            app.J3ValueEditField.Value = app.p1(3);

            app.updateArm

            end
        end

        % Button pushed function: SaveP2Button
        function SaveP2ButtonPushed(app, event)
            app.p2= [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3)];
        end

        % Button pushed function: SaveP3Button
        function SaveP3ButtonPushed(app, event)
            app.p3 = [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3)];
        end

        % Button pushed function: GoP2Button
        function GoP2ButtonPushed(app, event)
            if length(app.p2) == 3
            app.jointAngles(1) = app.p2(1);
            app.jointAngles(2) = app.p2(2);
            app.jointAngles(3) = app.p2(3);

            app.Joint1Slider.Value = app.p2(1);
            app.Joint2Slider.Value = app.p2(2);
            app.Joint3Slider.Value = app.p2(3);

            app.J1ValueEditField.Value = app.p2(1);
            app.J2ValueEditField.Value = app.p2(2);
            app.J3ValueEditField.Value = app.p2(3);

            app.updateArm

            end
        end

        % Button pushed function: GoP3Button
        function GoP3ButtonPushed(app, event)
            if length(app.p3) == 3
            app.jointAngles(1) = app.p3(1);
            app.jointAngles(2) = app.p3(2);
            app.jointAngles(3) = app.p3(3);

            app.Joint1Slider.Value = app.p3(1);
            app.Joint2Slider.Value = app.p3(2);
            app.Joint3Slider.Value = app.p3(3);

            app.J1ValueEditField.Value = app.p3(1);
            app.J2ValueEditField.Value = app.p3(2);
            app.J3ValueEditField.Value = app.p3(3);

            app.updateArm

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1Label = uilabel(app.UIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint  2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.J1ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.SaveP1Button = uibutton(app.UIFigure, 'push');
            app.SaveP1Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP1ButtonPushed, true);
            app.SaveP1Button.Text = 'Save P1';
            app.SaveP2Button = uibutton(app.UIFigure, 'push');
            app.SaveP2Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP2ButtonPushed, true);
            app.SaveP2Button.Text = 'Save P2';
            app.SaveP3Button = uibutton(app.UIFigure, 'push');
            app.SaveP3Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP3ButtonPushed, true);
            app.SaveP3Button.Text = 'Save P3';
            app.GoP1Button = uibutton(app.UIFigure, 'push');
            app.GoP1Button.ButtonPushedFcn = createCallbackFcn(app, @GoP1ButtonPushed, true);
            app.GoP1Button.Text = 'Go P1';
            app.GoP2Button = uibutton(app.UIFigure, 'push');
            app.GoP2Button.ButtonPushedFcn = createCallbackFcn(app, @GoP2ButtonPushed, true);
            app.GoP2Button.Text = 'Go P2';
            app.GoP3Button = uibutton(app.UIFigure, 'push');
            app.GoP3Button.ButtonPushedFcn = createCallbackFcn(app, @GoP3ButtonPushed, true);
            app.GoP3Button.Text = 'Go P3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArmGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end