classdef exam1Matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ServerResponseTextArea       matlab.ui.control.TextArea
        ServerResponseTextAreaLabel  matlab.ui.control.Label
        FlashButton                  matlab.ui.control.Button
        PeriodmsEditField            matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel       matlab.ui.control.Label
        NumFlashesEditField          matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel     matlab.ui.control.Label
        LEDOffButton                 matlab.ui.control.Button
        LEDOnButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            result = sendCommand("led/on");
            app.ServerResponseTextArea.Value = result;
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            result = sendCommand("led/off");
            app.ServerResponseTextArea.Value = result;
        end

        % Value changed function: NumFlashesEditField
        function NumFlashesEditFieldValueChanged(app, event)
            Value = app.NumFlashesEditField.Value; 
            
        end

        % Value changed function: PeriodmsEditField
        function PeriodmsEditFieldValueChanged(app, event)
            Value = app.PeriodmsEditField.Value;
            
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            numFlash = app.NumFlashesEditField.Value;
            periodFlash = app.PeriodmsEditField.Value;
            commandString = sprintf("flash/%i/%i", numFlash, periodFlash);
            result = sendCommand(commandString);
            app.ServerResponseTextArea.Value = result;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDOnButton = uibutton(app.UIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.UIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.NumFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes';
            app.NumFlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumFlashesEditField.ValueChangedFcn = createCallbackFcn(app, @NumFlashesEditFieldValueChanged, true);
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodmsEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodmsEditFieldValueChanged, true);
            app.FlashButton = uibutton(app.UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            app.ServerResponseTextAreaLabel = uilabel(app.UIFigure);
            app.ServerResponseTextAreaLabel.Text = 'Server Response';
            app.ServerResponseTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exam1Matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end