classdef BYOB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AnimateButton                  matlab.ui.control.Button
        EndingLocationDropDown         matlab.ui.control.DropDown
        EndingLocationDropDownLabel    matlab.ui.control.Label
        StartingLocationDropDown       matlab.ui.control.DropDown
        StartingLocationDropDownLabel  matlab.ui.control.Label
        GoButton_5                     matlab.ui.control.Button
        SetButton_4                    matlab.ui.control.Button
        GoButton_4                     matlab.ui.control.Button
        SetButton_3                    matlab.ui.control.Button
        GoButton_3                     matlab.ui.control.Button
        SetButton_2                    matlab.ui.control.Button
        GoButton_2                     matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        GoButton                       matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Theta4EditField                matlab.ui.control.NumericEditField
        Theta4EditFieldLabel           matlab.ui.control.Label
        Theta3EditField                matlab.ui.control.NumericEditField
        Theta3EditFieldLabel           matlab.ui.control.Label
        Theta2EditField                matlab.ui.control.NumericEditField
        Theta2EditFieldLabel           matlab.ui.control.Label
        Theta1EditField                matlab.ui.control.NumericEditField
        Theta1EditFieldLabel           matlab.ui.control.Label
        Theta4Slider                   matlab.ui.control.Slider
        Theta4SliderLabel              matlab.ui.control.Label
        Theta3Slider                   matlab.ui.control.Slider
        Theta3SliderLabel              matlab.ui.control.Label
        Theta2Slider                   matlab.ui.control.Slider
        Theta2SliderLabel              matlab.ui.control.Label
        Theta1Slider                   matlab.ui.control.Slider
        Theta1SliderLabel              matlab.ui.control.Label
        axes_arm                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices


        jointAngles = [0 -90 -90 0];

        p1 = [0 0 0 0];
        p2 = [0 0 0 0];
        p3 = [0 0 0 0];
        p4 = [0 0 0 0];

    end
    
    methods (Access = private)
        
        function updateArm(app)

        % Create the A homogeneous transformation matrices for the given jointAngles.
        A1 = create_A_matrix(70, 0, 180, app.jointAngles(1));
        A2 = create_A_matrix(70, 0, 180, app.jointAngles(2));
        A3 = create_A_matrix(0, 10, 90, app.jointAngles(3));
        A4 = create_A_matrix(0, -20, 0, app.jointAngles(4));

        
        % Use the current A matricies to form the T0_n matricies.
        T0_1 = A1;
        T0_2 = A1 * A2;
        T0_3 = A1 * A2 * A3;
        T0_4 = A1 * A2 * A3 * A4;

        
        % Use the current T matricies to transform the original patch vertices
        link1VerticesWRTground = T0_1 * app.link1Vertices;
        link2VerticesWRTground = T0_2 * app.link2Vertices;
        link3VerticesWRTground = T0_3 * app.link3Vertices;
        link4VerticesWRTground = T0_4 * app.link4Vertices;

        
        % Update the patches with the newly transformed vertices
        set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
        set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
        set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

  % Prepare the axes_arm properties
    app.axes_arm.View = [90 -50];
    app.axes_arm.Visible = 'off';
    
    % Once you are in Matlab App Designer you can try these axes_arm properties too.
    % These properties keep the Axes from automatically resizing (optional)
    app.axes_arm.XLim = [-200 300];
    app.axes_arm.YLim = [-200 300];
    app.axes_arm.ZLim = [-200 300];
    
    % Create vertices for all the patches
    makeStlLink("BYOB0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
    % Save references to the vertices of each patch, make points 4x1 not 3x1.
    app.link1Patch = makeStlLink("BYOB1.stl", app.axes_arm, [112/255 198/255 210/255]);
    app.link1Vertices = get(app.link1Patch, 'Vertices')';
    app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));


    app.link2Patch = makeStlLink("BYOB1.stl", app.axes_arm, [198/255 172/255 20/255]);
    app.link2Vertices = get(app.link2Patch, 'Vertices')';
    app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));


    app.link3Patch = makeStlLink("BYOB3.stl", app.axes_arm, [181/255 24/255 35/255]);
    app.link3Vertices = get(app.link3Patch, 'Vertices')';
    app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));


    app.link4Patch = makeStlLink("BYOB4.stl", app.axes_arm, [212/255 115/255 90/255]);
    app.link4Vertices = get(app.link4Patch, 'Vertices')';
    app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

    % preset 
    app.Label.Text = string(app.p1(1) + ", " + app.p1(2) + ", " + app.p1(3) + ", " + app.p1(4));
    app.Label_2.Text = string(app.p2(1) + ", " + app.p2(2) + ", " + app.p2(3) + ", " + app.p2(4));
    app.Label_3.Text = string(app.p3(1) + ", " + app.p3(2) + ", " + app.p3(3) + ", " + app.p3(4));
    app.Label_4.Text = string(app.p4(1) + ", " + app.p4(2) + ", " + app.p4(3) + ", " + app.p4(4));

    % End: Code that can go into your GUI's opening function.

    app.StartingLocationDropDown.Items     = {'Preset 1','Preset 2','Preset 3','Preset 4'};
    app.StartingLocationDropDown.ItemsData = [1 2 3 4];
    app.StartingLocationDropDown.Value     = 1;

    app.EndingLocationDropDown.Items     = {'Preset 1','Preset 2','Preset 3','Preset 4'};
    app.EndingLocationDropDown.ItemsData = [1 2 3 4];
    app.EndingLocationDropDown.Value     = 1;
    
    updateArm(app);


        end

        % Callback function
        function AllthetaSliderValueChanged(app, event)
        
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            value = app.Theta1Slider.Value;
            app.jointAngles(1) = value;
            app.Theta1EditField.Value = value;
            app.updateArm
            
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            value = app.Theta2Slider.Value;
            app.jointAngles(2) = -value - 90;
            app.Theta2EditField.Value = value;
            app.updateArm
            
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            value = app.Theta3Slider.Value;
            app.jointAngles(3) = value - 90;
            app.Theta3EditField.Value = value;
            app.updateArm
            
        end

        % Value changed function: Theta4Slider
        function Theta4SliderValueChanged(app, event)
            value = app.Theta4Slider.Value;
            app.jointAngles(4) = value;
            app.Theta4EditField.Value = value;
            app.updateArm
            
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            value = app.Theta1EditField.Value;
            app.jointAngles(1) = value;
            
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            value = app.Theta2EditField.Value;
            app.jointAngles(2) = -value - 90;
            
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            value = app.Theta3EditField.Value;
            app.jointAngles(3) = value - 90;
            
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            value = app.Theta4EditField.Value;
            app.jointAngles(4) = value;
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.Theta1Slider.Value = app.Theta1EditField.Value;
            app.Theta2Slider.Value = app.Theta2EditField.Value;
            app.Theta3Slider.Value = app.Theta3EditField.Value;
            app.Theta4Slider.Value = app.Theta4EditField.Value;
            app.updateArm
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.jointAngles = [0 -90 -90 0];
            app.Theta1Slider.Value = 0;
            app.Theta2Slider.Value = 0;
            app.Theta3Slider.Value = 0;
            app.Theta4Slider.Value = 0;
            app.updateArm
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.p1 = [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4)];
            app.Label.Text = string(app.p1(1) + ", " + app.p1(2) + ", " + app.p1(3) + ", " + app.p1(4));
            app.Theta1Slider.Value = app.p1(1);
            app.Theta2Slider.Value = app.p1(2);
            app.Theta3Slider.Value = app.p1(3);
            app.Theta4Slider.Value = app.p1(4);
            app.Theta1EditField.Value = app.p1(1);
            app.Theta2EditField.Value = app.p1(2);
            app.Theta3EditField.Value = app.p1(3);
            app.Theta4EditField.Value = app.p1(4);
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            app.p2 = [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4)];
            app.Label_2.Text = string(app.p2(1) + ", " + app.p2(2) + ", " + app.p2(3) + ", " + app.p2(4));
            app.Theta1Slider.Value = app.jointAngles(1);
            app.Theta2Slider.Value = app.jointAngles(2);
            app.Theta3Slider.Value = app.jointAngles(3);
            app.Theta4Slider.Value = app.jointAngles(4);
            app.Theta1EditField.Value = app.jointAngles(1);
            app.Theta2EditField.Value = app.jointAngles(2);
            app.Theta3EditField.Value = app.jointAngles(3);
            app.Theta4EditField.Value = app.jointAngles(4);
        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
            app.p3 = [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4)];
            app.Label_3.Text = string(app.p3(1) + ", " + app.p3(2) + ", " + app.p3(3) + ", " + app.p3(4));
            app.Theta1Slider.Value = app.jointAngles(1);
            app.Theta2Slider.Value = app.jointAngles(2);
            app.Theta3Slider.Value = app.jointAngles(3);
            app.Theta4Slider.Value = app.jointAngles(4);
            app.Theta1EditField.Value = app.jointAngles(1);
            app.Theta2EditField.Value = app.jointAngles(2);
            app.Theta3EditField.Value = app.jointAngles(3);
            app.Theta4EditField.Value = app.jointAngles(4);
        end

        % Button pushed function: SetButton_4
        function SetButton_4Pushed(app, event)
            app.p4 = [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4)];
            app.Label_4.Text = string(app.p4(1) + ", " + app.p4(2) + ", " + app.p4(3) + ", " + app.p4(4));
            app.Theta1Slider.Value = app.jointAngles(1);
            app.Theta2Slider.Value = app.jointAngles(2);
            app.Theta3Slider.Value = app.jointAngles(3);
            app.Theta4Slider.Value = app.jointAngles(4);
            app.Theta1EditField.Value = app.jointAngles(1);
            app.Theta2EditField.Value = app.jointAngles(2);
            app.Theta3EditField.Value = app.jointAngles(3);
            app.Theta4EditField.Value = app.jointAngles(4);
        end

        % Button pushed function: GoButton_2
        function GoButton_2Pushed(app, event)
            app.jointAngles = app.p1;
            app.updateArm
        end

        % Button pushed function: GoButton_3
        function GoButton_3Pushed(app, event)
            app.jointAngles = app.p2;
            app.updateArm
        end

        % Button pushed function: GoButton_4
        function GoButton_4Pushed(app, event)
            app.jointAngles = app.p3;
            app.updateArm
        end

        % Button pushed function: GoButton_5
        function GoButton_5Pushed(app, event)
            app.jointAngles = app.p4;
            app.updateArm
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            presets    = [app.p1; app.p2; app.p3; app.p4];   % 4x4, one preset per row
            startPoint = presets(app.StartingLocationDropDown.Value, :);
            endPoint   = presets(app.EndingLocationDropDown.Value, :);

            posList = zeros(100, 4);

            for j = 1:4
                posList(:,j) = linspace(startPoint(j), endPoint(j), 100);
            end

            for i = 1:100
                app.jointAngles = posList(i,:);
                app.Theta1Slider.Value = app.jointAngles(1);
                app.Theta2Slider.Value = app.jointAngles(2);
                app.Theta3Slider.Value = app.jointAngles(3);
                app.Theta4Slider.Value = app.jointAngles(4);
                app.Theta1EditField.Value = app.jointAngles(1);
                app.Theta2EditField.Value = app.jointAngles(2);
                app.Theta3EditField.Value = app.jointAngles(3);
                app.Theta4EditField.Value = app.jointAngles(4);
                pause(0.01)
                app.updateArm
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta4SliderLabel = uilabel(app.UIFigure);
            app.Theta4SliderLabel.Text = 'Theta 4';
            app.Theta4Slider = uislider(app.UIFigure);
            app.Theta4Slider.Limits = [-180 180];
            app.Theta4Slider.ValueChangedFcn = createCallbackFcn(app, @Theta4SliderValueChanged, true);
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta 4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.GoButton_2 = uibutton(app.UIFigure, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go';
            app.SetButton_2 = uibutton(app.UIFigure, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.GoButton_3 = uibutton(app.UIFigure, 'push');
            app.GoButton_3.ButtonPushedFcn = createCallbackFcn(app, @GoButton_3Pushed, true);
            app.GoButton_3.Text = 'Go';
            app.SetButton_3 = uibutton(app.UIFigure, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Text = 'Set';
            app.GoButton_4 = uibutton(app.UIFigure, 'push');
            app.GoButton_4.ButtonPushedFcn = createCallbackFcn(app, @GoButton_4Pushed, true);
            app.GoButton_4.Text = 'Go';
            app.SetButton_4 = uibutton(app.UIFigure, 'push');
            app.SetButton_4.ButtonPushedFcn = createCallbackFcn(app, @SetButton_4Pushed, true);
            app.SetButton_4.Text = 'Set';
            app.GoButton_5 = uibutton(app.UIFigure, 'push');
            app.GoButton_5.ButtonPushedFcn = createCallbackFcn(app, @GoButton_5Pushed, true);
            app.GoButton_5.Text = 'Go';
            app.StartingLocationDropDownLabel = uilabel(app.UIFigure);
            app.StartingLocationDropDownLabel.Text = 'Starting Location';
            app.StartingLocationDropDown = uidropdown(app.UIFigure);
            app.StartingLocationDropDown.ItemsData = {'app.p1', 'app.p2', 'app.p3', 'app.p4'};
            app.StartingLocationDropDown.Value = 'app.p1';
            app.EndingLocationDropDownLabel = uilabel(app.UIFigure);
            app.EndingLocationDropDownLabel.Text = 'Ending Location';
            app.EndingLocationDropDown = uidropdown(app.UIFigure);
            app.EndingLocationDropDown.ItemsData = {'app.p1', 'app.p2', 'app.p3', 'app.p4'};
            app.EndingLocationDropDown.Value = 'app.p1';
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BYOB_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end