classdef MATLAB_GPIO_MQTT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SendLEDsButton     matlab.ui.control.Button
        Counter0Label      matlab.ui.control.Label
        GreenSwitch        matlab.ui.control.ToggleSwitch
        GreenSwitchLabel   matlab.ui.control.Label
        YellowSwitch       matlab.ui.control.ToggleSwitch
        YellowSwitchLabel  matlab.ui.control.Label
        RedSwitch          matlab.ui.control.ToggleSwitch
        RedSwitchLabel     matlab.ui.control.Label
        GreenOffButton     matlab.ui.control.Button
        GreenOnButton      matlab.ui.control.Button
        YellowOffButton    matlab.ui.control.Button
        YellowOnButton     matlab.ui.control.Button
        RedOffButton       matlab.ui.control.Button
        RedOnButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient
        counter = 0;
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type

            if messageType == "button"
                app.counter = app.counter + 1;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
            end
            
            if messageType == "reset"
                app.counter = 0;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
            end 
        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            messageStruct.payload = messagePayload;
            messageJson = jsonencode(messageStruct);
        
            % write(app.mqClient, "chat", messageJson)
            write(app.mqClient, "me435/jessophc/chat","FileType")
            fprintf("sent: %s\n", messageJson)
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            subscribe(app.mqClient, "me435/jessophc", "Callback", @app.myCallback)


        end

        % Button pushed function: RedOnButton
        function RedOnButtonPushed(app, event)
            % Button press should push a message
        end

        % Button pushed function: RedOffButton
        function RedOffButtonPushed(app, event)
            % Button press should push a message
        end

        % Button pushed function: YellowOnButton
        function YellowOnButtonPushed(app, event)
            % Button press should push a message
        end

        % Button pushed function: YellowOffButton
        function YellowOffButtonPushed(app, event)
            % Button press should push a message
        end

        % Button pushed function: GreenOnButton
        function GreenOnButtonPushed(app, event)
            % Button press should push a message
        end

        % Button pushed function: GreenOffButton
        function GreenOffButtonPushed(app, event)
            % Button press should push a message
        end

        % Value changed function: RedSwitch
        function RedSwitchValueChanged(app, event)
            value = app.RedSwitch.Value;
            % Switches should change a preset matrix and not send anything
        end

        % Value changed function: YellowSwitch
        function YellowSwitchValueChanged(app, event)
            value = app.YellowSwitch.Value;
             % Switches should change a preset matrix and not send anything
        end

        % Value changed function: GreenSwitch
        function GreenSwitchValueChanged(app, event)
            value = app.GreenSwitch.Value;
             % Switches should change a preset matrix and not send anything
        end

        % Button pushed function: SendLEDsButton
        function SendLEDsButtonPushed(app, event)
             % Should handle all three switch values and send their info
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedOnButton = uibutton(app.UIFigure, 'push');
            app.RedOnButton.ButtonPushedFcn = createCallbackFcn(app, @RedOnButtonPushed, true);
            app.RedOnButton.Text = 'Red On';
            app.RedOffButton = uibutton(app.UIFigure, 'push');
            app.RedOffButton.ButtonPushedFcn = createCallbackFcn(app, @RedOffButtonPushed, true);
            app.RedOffButton.Text = 'Red Off';
            app.YellowOnButton = uibutton(app.UIFigure, 'push');
            app.YellowOnButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOnButtonPushed, true);
            app.YellowOnButton.Text = 'Yellow On';
            app.YellowOffButton = uibutton(app.UIFigure, 'push');
            app.YellowOffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOffButtonPushed, true);
            app.YellowOffButton.Text = 'Yellow Off';
            app.GreenOnButton = uibutton(app.UIFigure, 'push');
            app.GreenOnButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOnButtonPushed, true);
            app.GreenOnButton.Text = 'Green On';
            app.GreenOffButton = uibutton(app.UIFigure, 'push');
            app.GreenOffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOffButtonPushed, true);
            app.GreenOffButton.Text = 'Green Off';
            app.RedSwitchLabel = uilabel(app.UIFigure);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RedSwitch.ValueChangedFcn = createCallbackFcn(app, @RedSwitchValueChanged, true);
            app.YellowSwitchLabel = uilabel(app.UIFigure);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.UIFigure, 'toggle');
            app.YellowSwitch.ValueChangedFcn = createCallbackFcn(app, @YellowSwitchValueChanged, true);
            app.GreenSwitchLabel = uilabel(app.UIFigure);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.UIFigure, 'toggle');
            app.GreenSwitch.ValueChangedFcn = createCallbackFcn(app, @GreenSwitchValueChanged, true);
            app.Counter0Label = uilabel(app.UIFigure);
            app.Counter0Label.Text = 'Counter = 0';
            app.SendLEDsButton = uibutton(app.UIFigure, 'push');
            app.SendLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SendLEDsButtonPushed, true);
            app.SendLEDsButton.Text = 'Send LEDs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_GPIO_MQTT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end